classdef dataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        TimecolDropDown          matlab.ui.control.DropDown
        TimecolDropDownLabel     matlab.ui.control.Label
        SignalcolDropDown        matlab.ui.control.DropDown
        SignalcolDropDownLabel   matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        FilenameEditFieldLabel   matlab.ui.control.Label
        WriteCsvButton           matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        TimeEditField            matlab.ui.control.NumericEditField
        TimeEditFieldLabel       matlab.ui.control.Label
        GenerateButton           matlab.ui.control.Button
        FileLabel                matlab.ui.control.Label
        LoadFileButton           matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        MeanLabel                matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data
        sig
        mean
    end
    
    methods (Access = private, Static)
        
        function [sig, mean] = SignalProc(ref, reftime, time, threshold)

    mark = 0;
    state = false;
    sig = zeros(0,2);
    j = 2;
    mean = 0;
    sig(1,1) = 0;

    if(ref(1) > threshold)
        sig(1,2) = 1;
        mark = time;
        state = true;
    end

    % Main Signal Process Logic
    for i = 2:size(ref,1)
    
        if(ref(i) > threshold)
            mark = reftime(i) + time;
            if(~state)
                sig(j,2) = 0;
                sig(j,1) = reftime(i)-0.0001;
                j = j+1;

                sig(j,2) = 1;
                sig(j,1) = reftime(i);
                j = j+1;
                state = true;
            end
        end
    
        if(mark < reftime(i) && state)
            sig(j,2) = 1;
            sig(j,1) = mark;
            
            mean = mean + sig(j,1) - sig(j-1,1);

            j = j+1;

            sig(j,2) = 0;
            sig(j,1) = mark + 0.0001;
            j = j+1;
            state = false;
        end
    end

    if(state)
        sig(j,2) = 1;
    else
        sig(j,2) = 0;
    end

    sig(j,1) = reftime(size(reftime,1));

    if(mean~=0)
        mean = mean/reftime(size(reftime,1));
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            app.FileLabel.Text = path;
            format longG;
            app.data = readmatrix(file, "Delimiter", ',','DecimalSeparator','.');
            app.TimecolDropDown.Items = string(1:size(app.data,2));
            app.SignalcolDropDown.Items = string(1:size(app.data,2));
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            ref = app.data(:,str2double(app.SignalcolDropDown.Value));
            reftime = app.data(:, str2double(app.TimecolDropDown.Value));
            [app.sig, app.mean] = app.SignalProc(ref, reftime, app.TimeEditField.Value, app.ThresholdEditField.Value);
            plot(app.UIAxes, app.sig(:,1), app.sig(:,2));
            app.MeanLabel.Text = "Mean : " + app.mean;
        end

        % Button pushed function: WriteCsvButton
        function WriteCsvButtonPushed(app, event)
            % Write to a comma delimited csv file
          writematrix(app.sig, app.FilenameEditField.Value + ".csv");

          writematrix(app.mean, "PBit_" + app.FilenameEditField.Value + ".csv");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FileLabel = uilabel(app.LeftPanel);
            app.FileLabel.Text = 'File : ';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.TimeEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WriteCsvButton = uibutton(app.LeftPanel, 'push');
            app.WriteCsvButton.ButtonPushedFcn = createCallbackFcn(app, @WriteCsvButtonPushed, true);
            app.WriteCsvButton.Text = 'Write Csv';
            app.FilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.SignalcolDropDownLabel = uilabel(app.LeftPanel);
            app.SignalcolDropDownLabel.Text = 'Signal col';
            app.SignalcolDropDown = uidropdown(app.LeftPanel);
            app.SignalcolDropDown.Items = {};
            app.SignalcolDropDown.Value = {};
            app.TimecolDropDownLabel = uilabel(app.LeftPanel);
            app.TimecolDropDownLabel.Text = 'Time col';
            app.TimecolDropDown = uidropdown(app.LeftPanel);
            app.TimecolDropDown.Items = {};
            app.TimecolDropDown.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MeanLabel = uilabel(app.RightPanel);
            app.MeanLabel.Text = 'Mean ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end